classdef response_finder_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ManualTab                  matlab.ui.container.Tab
        ResponseButton             matlab.ui.control.Button
        planeDisplay               matlab.ui.control.NumericEditField
        SelectButton               matlab.ui.control.Button
        ZPlaneSlider               matlab.ui.control.Slider
        ZPlaneSliderLabel          matlab.ui.control.Label
        ResolutionFactorKnob       matlab.ui.control.DiscreteKnob
        ResolutionFactorKnobLabel  matlab.ui.control.Label
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        LoadTiffButton             matlab.ui.control.Button
        LoadStimButton             matlab.ui.control.Button
        SavePositionButton         matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        AverageButton              matlab.ui.control.Button
        coordinateList             matlab.ui.control.Table
        PlotPostionButton          matlab.ui.control.Button
        SelectCellButton           matlab.ui.control.Button
        ManualButton               matlab.ui.control.Button
        LoadConfigButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        SemiautoTab                matlab.ui.container.Tab
        RunScanButton              matlab.ui.control.Button
        SelectCellButton_2         matlab.ui.control.Button
        ZPlaneSlider_2Label        matlab.ui.control.Label
        ZPlaneSlider_2             matlab.ui.control.Slider
        planeDisplay_2             matlab.ui.control.NumericEditField
        coordinateList_2           matlab.ui.control.Table
        SavePositionButton_2       matlab.ui.control.Button
        ExportButton_2             matlab.ui.control.Button
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes7                    matlab.ui.control.UIAxes
        AutoTab                    matlab.ui.container.Tab
        ofPointsEditFieldLabel     matlab.ui.control.Label
        ofPointsEditField          matlab.ui.control.NumericEditField
        ZMaxEditFieldLabel         matlab.ui.control.Label
        ZMaxEditField              matlab.ui.control.NumericEditField
        ZMinEditFieldLabel         matlab.ui.control.Label
        ZMinEditField              matlab.ui.control.NumericEditField
        ExportButton_3             matlab.ui.control.Button
        RunScanButton_2            matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        NextButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        coordinateList_3           matlab.ui.control.Table
        ZPlaneSlider_3Label        matlab.ui.control.Label
        ZPlaneSlider_3             matlab.ui.control.Slider
        planeDisplay_3             matlab.ui.control.NumericEditField
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTiffButton
        function LoadTiffButtonPushed(app, event)
            global var
            var.coord_points = [];
            [file, path] = uigetfile('*.tif','Select Tiff Files','Multiselect','on');
            if iscell(file)
                var.multi_file = 1;
                for i = 1:length(file)
                    var.frame_per_file(i) = length(imfinfo([path file{i}]));
                end
                var.num_file = length(file);
                var.num_frames = sum(var.frame_per_file);
                var.fileName = [path file{1}];
                var.file = file;
                var.path = path;
            else
                var.multi_file = 0;
                var.num_file = 1;
                var.num_frames = length(imfinfo([path file]));
                var.fileName = [path file];
                var.file{1} = file;
                var.frame_per_file = length(imfinfo([path file]));
                var.path = path;
            end
            data = imread(var.fileName,1);
            imagesc(app.UIAxes,data)
            app.UIAxes.YLim = [0 size(data,1)];
            app.UIAxes.XLim = [0 size(data,2)];
            app.UIAxes.YLim = [0 size(data,1)];
            app.UIAxes.XLim = [0 size(data,2)];
            var.count = 0;
            var.count_semi_auto = 0;
            var.count_auto = 0;
            var.scan_flag = 0;
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes2.Toolbar.Visible = 'on';
            app.UIAxes3.Toolbar.Visible = 'on';
            app.UIAxes4.Toolbar.Visible = 'off';
            app.UIAxes5.Toolbar.Visible = 'off';
            
        end

        % Button pushed function: LoadStimButton
        function LoadStimButtonPushed(app, event)
            global var
            [file, path] = uigetfile('*.mat');
            stim_struct = load([path file]);
            var.stim_onset = stim_struct.stim_onset_frame;
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            global var
            [file, path] = uigetfile('*.mat');
            param_struct = load([path file]);
            var.num_average = param_struct.num_average;
            var.pre_stim_frame = param_struct.pre_stim_frame;
            var.post_stim_frame = param_struct.post_stim_frame;
            var.num_z_planes = param_struct.num_z_planes;
            app.ZPlaneSlider.Limits = [0 var.num_z_planes];
            if var.num_z_planes<=10
                app.ZPlaneSlider.MajorTicks = min(app.ZPlaneSlider.Limits):1:max(app.ZPlaneSlider.Limits);
                app.ZPlaneSlider_2.MajorTicks = min(app.ZPlaneSlider_2.Limits):1:max(app.ZPlaneSlider_2.Limits);
            elseif var.num_z_planes>10 && var.num_z_planes<=20
                app.ZPlaneSlider.MajorTicks = min(app.ZPlaneSlider.Limits):2:max(app.ZPlaneSlider.Limits);
                app.ZPlaneSlider_2.MajorTicks = min(app.ZPlaneSlider_2.Limits):2:max(app.ZPlaneSlider_2.Limits);
            else
                app.ZPlaneSlider.MajorTicks = min(app.ZPlaneSlider.Limits):5:max(app.ZPlaneSlider.Limits);
                app.ZPlaneSlider_2.MajorTicks = min(app.ZPlaneSlider_2.Limits):5:max(app.ZPlaneSlider_2.Limits);
            end
        end

        % Callback function
        function PlanesSpinnerValueChanged(app, event)
            global var
            value = app.PlanesSpinner.Value;
            var.num_z_planes = value;
            app.ZPlaneSlider.Limits = [0 var.num_z_planes];
            if var.num_z_planes<=10
                app.ZPlaneSlider.MajorTicks = min(app.ZPlaneSlider.Limits):1:max(app.ZPlaneSlider.Limits);
            elseif var.num_z_planes>10 && var.num_z_planes<=20
                app.ZPlaneSlider.MajorTicks = min(app.ZPlaneSlider.Limits):2:max(app.ZPlaneSlider.Limits);
            else
                app.ZPlaneSlider.MajorTicks = min(app.ZPlaneSlider.Limits):5:max(app.ZPlaneSlider.Limits);
            end
        end

        % Value changed function: ZPlaneSlider
        function ZPlaneSliderValueChanged(app, event)
            global var
            var.zvalue = round(app.ZPlaneSlider.Value);
            if var.zvalue ~= 0
                imagesc(app.UIAxes,imread(var.fileName,var.zvalue));
                app.planeDisplay.Value = var.zvalue;
            end
        end

        % Value changed function: planeDisplay
        function planeDisplayValueChanged(app, event)
            global var
            var.zvalue = app.planeDisplay.Value;
            app.ZPlaneSlider.Value = var.zvalue;
            if var.zvalue ~= 0
                imagesc(app.UIAxes,imread(var.fileName,var.zvalue));
            end
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            global var
            clear var.all_z_slice
            seq_temp = var.zvalue:var.num_z_planes:var.num_frames;
            
            if length(seq_temp)>var.num_average
                seq = seq_temp(1:var.num_average);
            else
                seq = seq_temp;
            end
            
            sample_frame = imread(var.fileName, seq(1));
            all_z_slice = zeros(size(sample_frame,1),size(sample_frame,2),length(seq));
            for i = 1:length(seq)
                all_z_slice(:,:,i) = imread(var.fileName, seq(i));
            end
            imagesc(app.UIAxes,mean(all_z_slice,3));
            var.all_z_slice = all_z_slice;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            global var
            bottom_left = drawpoint(app.UIAxes,'Color','r','InteractionsAllowed','None','HandleVisibility','off','Visible','off');
            top_right = drawpoint(app.UIAxes,'Color','r','InteractionsAllowed','None','HandleVisibility','off','Visible','off');
            X = [bottom_left.Position(1); top_right.Position(1)];
            Y = [bottom_left.Position(2); top_right.Position(2)];
            
            var.xmin = round(min(X)); var.xmax = round(max(X));
            var.ymin = round(min(Y)); var.ymax = round(max(Y));
            xs = [var.xmin var.xmax var.xmax var.xmin var.xmin];
            ys = [var.ymin var.ymin var.ymax var.ymax var.ymin];
            
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,xs, ys, 'r-');
            zoom_mean = mean(var.all_z_slice(var.ymin:var.ymax,var.xmin:var.xmax,:),3);
            imagesc(app.UIAxes2,zoom_mean)
            app.UIAxes2.YLim = [1 size(zoom_mean,1)];
            app.UIAxes2.XLim = [1 size(zoom_mean,2)];
            %             app.ResolutionFactorKnob.Value = 0;
            cla(app.UIAxes3)
        end

        % Value changed function: ResolutionFactorKnob
        function ResolutionFactorKnobValueChanged(app, event)
            global var
            var.scale_factor = str2double(app.ResolutionFactorKnob.Value);
            if var.scale_factor == 0
                imagesc(app.UIAxes3,mean(var.all_z_slice(var.ymin:var.ymax,var.xmin:var.xmax,:),3))
                var.final_stack = var.all_z_slice(var.ymin:var.ymax,var.xmin:var.xmax,:);
                app.UIAxes3.YLim = [1 size(var.final_stack,1)];
                app.UIAxes3.XLim = [1 size(var.final_stack,2)];
                var.scale_factor = 1;
            else
                clear all_z_slice_small
                size_temp = size(imresize(var.all_z_slice(var.ymin:var.ymax,var.xmin:var.xmax,1),1/var.scale_factor));
                all_z_slice_small = zeros(size_temp(1),size_temp(2), size(var.all_z_slice,3));
                for i = 1:size(var.all_z_slice,3)
                    all_z_slice_small(:,:,i) = imresize(var.all_z_slice(var.ymin:var.ymax,var.xmin:var.xmax,i),1/var.scale_factor);
                end
                imagesc(app.UIAxes3,mean(all_z_slice_small,3))
                app.UIAxes3.YLim = [1 size(all_z_slice_small,1)];
                app.UIAxes3.XLim = [1 size(all_z_slice_small,2)];
                var.resolved_imaged = mean(all_z_slice_small,3);
            end
        end

        % Button pushed function: ResponseButton
        function ResponseButtonPushed(app, event)
            global var
            clear cropped_trial var.frame_list frame_list
            seq = var.zvalue:var.num_z_planes:var.num_frames;
            var.stim_onset_corrected = seq(cell2mat(arrayfun(@(x) find(seq-x>0,1),var.stim_onset,'UniformOutput',false)));
            if var.multi_file == 1
                frame_list(1,:) = 1:sum(var.frame_per_file);
            else
                frame_list(1,:) = 1:var.frame_per_file;
            end
            frame_list(2,:) = cell2mat(arrayfun(@(x,y) ones(1,x)*y,var.frame_per_file,1:length(var.frame_per_file),'UniformOutput',false));
            frame_list(3,:) = cell2mat(arrayfun(@(x,y) 1:x, var.frame_per_file, 1:length(var.frame_per_file), 'UniformOutput', false));
            
            var.frame_list = frame_list;
            
            voxel_response = zeros(size(var.resolved_imaged,1),size(var.resolved_imaged,2),var.num_file);
            example_frame_size = size(imread(var.fileName,var.zvalue));
            
            for trial = 1:length(var.stim_onset_corrected)
                stim_index = find(seq==var.stim_onset_corrected(trial));
                pre_stim_index = seq((stim_index-var.pre_stim_frame):stim_index-1);
                post_stim_index = seq(stim_index:(stim_index+var.post_stim_frame)-1);
                
                which_vid = var.frame_list(2,[pre_stim_index post_stim_index]);
                which_frame = var.frame_list(3,[pre_stim_index post_stim_index]);
                
                current_trial = zeros(example_frame_size(1),example_frame_size(2),length(which_vid));
                cropped_trial = imresize(current_trial(var.ymin:var.ymax,var.xmin:var.xmax,length(which_vid)),1/var.scale_factor);
                
                for i = 1:length(which_vid)
                    current_trial(:,:,i) = imread([var.path var.file{which_vid(i)}], which_frame(i));
                    cropped_trial(:,:,i) = imresize(current_trial(var.ymin:var.ymax,var.xmin:var.xmax,i),1/var.scale_factor);
                    %                     current_trial(var.ymin:var.ymax,var.xmin:var.xmax,i);
                    %                     resize_trial(:,:,i) = imresize(cropped_trial(:,:,i))
                end
                
                voxel_response(:,:,trial) = mean(cropped_trial(:,:,var.pre_stim_frame+1:(var.pre_stim_frame+var.post_stim_frame)),3)...
                    - mean(cropped_trial(:,:,1:var.pre_stim_frame),3);
                
                var.all_cropped_trial{trial} = cropped_trial;
                clear current_trial cropped_trial
            end
            
            var.mean_voxel_response = mean(voxel_response,3);
            imagesc(app.UIAxes4,var.mean_voxel_response)
            app.UIAxes4.YLim = [1 size(voxel_response,1)];
            app.UIAxes4.XLim = [1 size(voxel_response,2)];
        end

        % Button pushed function: SelectCellButton
        function SelectCellButtonPushed(app, event)
            global var
            point_select = drawpoint(app.UIAxes4,'Color','r','InteractionsAllowed','None','HandleVisibility','off','Visible','off');
            var.pick_x = round(point_select.Position(1));
            var.pick_y = round(point_select.Position(2));
            app.XEditField.Value = (var.pick_x*var.scale_factor)+var.xmin;
            app.YEditField.Value = (var.pick_y*var.scale_factor)+var.ymin;
            
            clear pre_stim post_stim raw_response_trial f0 normalized_response
            hold(app.UIAxes5, 'off')
            for trial = 1:length(var.stim_onset_corrected)
                raw_response_trial = double(squeeze(var.all_cropped_trial{trial}(var.pick_y,var.pick_x,:)));
                f0 = mean(raw_response_trial(1:var.pre_stim_frame));
                normalized_response = (raw_response_trial-f0)/f0;
                plot(app.UIAxes5,(1:length(raw_response_trial))-var.pre_stim_frame,normalized_response);
                hold(app.UIAxes5, 'on')
            end
            plot(app.UIAxes5,[0 0],app.UIAxes5.YLim,'k')
        end

        % Button pushed function: PlotPostionButton
        function PlotPostionButtonPushed(app, event)
            global var
            hold(app.UIAxes4, 'on')
            plot(app.UIAxes4,var.pick_x,var.pick_y,'ro')
            hold(app.UIAxes4, 'off')
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3,var.pick_x,var.pick_y,'ro')
            hold(app.UIAxes3, 'off')
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,var.pick_x*var.scale_factor,var.pick_y*var.scale_factor,'ro')
            hold(app.UIAxes2, 'off')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,(var.pick_x*var.scale_factor)+var.xmin,(var.pick_y*var.scale_factor)+var.ymin,'ro')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            global var
            var.pick_x = round((app.XEditField.Value-var.xmin)/var.scale_factor);
            var.pick_y = round((app.YEditField.Value-var.ymin)/var.scale_factor);
            
            cla(app.UIAxes5);
            clear pre_stim post_stim raw_response_trial f0 normalized_response
            hold(app.UIAxes5, 'off')
            for trial = 1:length(var.stim_onset_corrected)
                raw_response_trial = double(squeeze(var.all_cropped_trial{trial}(var.pick_y,var.pick_x,:)));
                f0 = mean(raw_response_trial(1:var.pre_stim_frame));
                normalized_response = (raw_response_trial-f0)/f0;
                plot(app.UIAxes5,(1:length(raw_response_trial))-var.pre_stim_frame,normalized_response);
                hold(app.UIAxes5, 'on')
            end
            plot(app.UIAxes5,[0 0],app.UIAxes5.YLim,'k')
        end

        % Button pushed function: SavePositionButton
        function SavePositionButtonPushed(app, event)
            global var
            clear new_coord_points
            var.count = var.count + 1;
            new_coord_points = [var.count ...
                var.pick_x*(var.scale_factor)+var.xmin ...
                var.pick_y*(var.scale_factor)+var.ymin ...
                var.zvalue ...
                var.mean_voxel_response(var.pick_y,var.pick_x) ...
                {false}];
            var.coord_points = [var.coord_points; new_coord_points];
            T = array2table(var.coord_points);
            app.coordinateList.Data = T;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            coord_array = table2array(app.coordinateList.Data);
            coord_array_valid_temp = reshape([coord_array{:}],size(coord_array,1),[]);
            manual_coordinates = coord_array_valid_temp(coord_array_valid_temp(:,6)==1,2:4);
            
            [filename, pathname] = uiputfile('*.mat','Save Coordinate Variables As');
            newfilename = fullfile(pathname, filename);
            save(newfilename, 'manual_coordinates')
        end

        % Button pushed function: RunScanButton
        function RunScanButtonPushed(app, event)
            global var
            clear seq frame_list voxel_response
            voxel_response = zeros(var.num_z_planes,length(var.stim_onset),size(var.resolved_imaged,1),size(var.resolved_imaged,2));
            f = waitbar(0,'Please wait...');
            for j = 1:var.num_z_planes
                waitbar(j/var.num_z_planes,f,'Analysing...')
                seq = j:var.num_z_planes:var.num_frames;
                var.stim_onset_corrected = seq(cell2mat(arrayfun(@(x) find(seq-x>0,1),var.stim_onset,'UniformOutput',false)));
                if var.multi_file == 1
                    frame_list(1,:) = 1:sum(var.frame_per_file);
                else
                    frame_list(1,:) = 1:var.frame_per_file;
                end
                frame_list(2,:) = cell2mat(arrayfun(@(x,y) ones(1,x)*y,var.frame_per_file,1:length(var.frame_per_file),'UniformOutput',false));
                frame_list(3,:) = cell2mat(arrayfun(@(x,y) 1:x, var.frame_per_file, 1:length(var.frame_per_file), 'UniformOutput', false));
                
                var.frame_list_semi_auto = frame_list;
                
                example_frame_size = size(imread(var.fileName,j));
                
                for trial = 1:length(var.stim_onset_corrected)
                    stim_index = find(seq==var.stim_onset_corrected(trial));
                    pre_stim_index = seq((stim_index-var.pre_stim_frame):stim_index-1);
                    post_stim_index = seq(stim_index:(stim_index+var.post_stim_frame)-1);
                    
                    which_vid = var.frame_list_semi_auto(2,[pre_stim_index post_stim_index]);
                    which_frame = var.frame_list_semi_auto(3,[pre_stim_index post_stim_index]);
                    
                    current_trial = zeros(example_frame_size(1),example_frame_size(2),length(which_vid));
                    cropped_trial = imresize(current_trial(var.ymin:var.ymax,var.xmin:var.xmax,length(which_vid)),1/var.scale_factor);
                    
                    for i = 1:length(which_vid)
                        current_trial(:,:,i) = imread([var.path var.file{which_vid(i)}], which_frame(i));
                        cropped_trial(:,:,i) = imresize(current_trial(var.ymin:var.ymax,var.xmin:var.xmax,i),1/var.scale_factor);
                    end
                    
                    voxel_response(j,trial,:,:) = mean(cropped_trial(:,:,var.pre_stim_frame+1:(var.pre_stim_frame+var.post_stim_frame)),3)...
                        - mean(cropped_trial(:,:,1:var.pre_stim_frame),3);
                    
                    var.all_cropped_trial_semi_auto{j}{trial} = cropped_trial;
                    clear current_trial cropped_trial
                end
                
                var.mean_voxel_response_semi_auto{j} = squeeze(mean(squeeze(voxel_response(j,:,:,:)),1));
            end
            close(f)
            var.max_value = max(cell2mat(cellfun(@(x) max(max(x)), var.mean_voxel_response_semi_auto, 'UniformOutput', false)'));
            var.min_value = min(cell2mat(cellfun(@(x) min(min(x)), var.mean_voxel_response_semi_auto, 'UniformOutput', false)'));
            var.scan_flag = 1;
            var.coord_points_2 = [];
        end

        % Value changed function: planeDisplay_2
        function planeDisplay_2ValueChanged(app, event)
            global var
            var.zvalue = app.planeDisplay_2.Value;
            app.ZPlaneSlider_2.Value = var.zvalue;
            if var.zvalue ~= 0
                imagesc(app.UIAxes6,var.mean_voxel_response_semi_auto{var.zvalue},[var.min_value, var.max_value]);
            end
        end

        % Value changed function: ZPlaneSlider_2
        function ZPlaneSlider_2ValueChanged(app, event)
            global var
            var.zvalue = round(app.ZPlaneSlider_2.Value);
            if var.zvalue ~= 0
                imagesc(app.UIAxes6,var.mean_voxel_response_semi_auto{var.zvalue},[var.min_value, var.max_value]);
                app.planeDisplay_2.Value = var.zvalue;
                app.UIAxes6.YLim = [1 size(var.mean_voxel_response_semi_auto{var.zvalue},1)];
                app.UIAxes6.XLim = [1 size(var.mean_voxel_response_semi_auto{var.zvalue},2)];
            end
            
        end

        % Button pushed function: SelectCellButton_2
        function SelectCellButton_2Pushed(app, event)
            global var
            point_select = drawpoint(app.UIAxes6,'Color','r','InteractionsAllowed','None','HandleVisibility','off','Visible','off');
            var.pick_x = round(point_select.Position(1));
            var.pick_y = round(point_select.Position(2));
            
            clear pre_stim post_stim raw_response_trial f0 normalized_response
            hold(app.UIAxes7, 'off')
            for trial = 1:length(var.stim_onset_corrected)
                raw_response_trial = double(squeeze(var.all_cropped_trial_semi_auto{var.zvalue}{trial}(var.pick_y,var.pick_x,:)));
                f0 = mean(raw_response_trial(1:var.pre_stim_frame));
                normalized_response = (raw_response_trial-f0)/f0;
                plot(app.UIAxes7,(1:length(raw_response_trial))-var.pre_stim_frame,normalized_response);
                hold(app.UIAxes7, 'on')
            end
            plot(app.UIAxes7,[0 0],app.UIAxes7.YLim,'k')
        end

        % Button pushed function: SavePositionButton_2
        function SavePositionButton_2Pushed(app, event)
            global var
            clear new_coord_points_2
            var.count_semi_auto = var.count_semi_auto + 1;
            new_coord_points_2 = [var.count_semi_auto ...
                var.pick_x*(var.scale_factor)+var.xmin ...
                var.pick_y*(var.scale_factor)+var.ymin ...
                var.zvalue ...
                var.mean_voxel_response_semi_auto{var.zvalue}(var.pick_y,var.pick_x) ...
                {false}];
            var.coord_points_2 = [var.coord_points_2; new_coord_points_2];
            T = array2table(var.coord_points_2);
            app.coordinateList_2.Data = T;
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            globarl var
            coord_array_2 = table2array(app.coordinateList_2.Data);
            coord_array_valid_temp = reshape([coord_array_2{:}],size(coord_array_2,1),[]);
            semi_auto_coordinates = coord_array_valid_temp(coord_array_valid_temp(:,6)==1,2:4);
            
            [filename, pathname] = uiputfile('*.mat','Save Coordinate Variables As');
            newfilename = fullfile(pathname, filename);
            save(newfilename, 'semi_auto_coordinates')
        end

        % Value changed function: ofPointsEditField
        function ofPointsEditFieldValueChanged(app, event)
            global var
            var.ROI_num = app.ofPointsEditField.Value;
        end

        % Value changed function: ZMaxEditField
        function ZMaxEditFieldValueChanged(app, event)
            global var
            var.max_z_plane = app.ZMaxEditField.Value;
        end

        % Value changed function: ZMinEditField
        function ZMinEditFieldValueChanged(app, event)
            global var
            var.min_z_plane = app.ZMinEditField.Value;
        end

        % Button pushed function: RunScanButton_2
        function RunScanButton_2Pushed(app, event)
            global var
            clear  var.best_list seq frame_list voxel_response
            var.count_auto = 0;
            var.z_index_auto = var.min_z_plane:var.max_z_plane;
            if var.scan_flag == 0 % if user has not ran semi-auto
                voxel_response = zeros(length(var.z_index_auto),length(var.stim_onset),size(var.resolved_imaged,1),size(var.resolved_imaged,2));
                f = waitbar(0,'Please wait...');
                for j = 1:length(var.z_index_auto)
                    waitbar(j/length(var.z_index_auto),f,'Analysing...')
                    seq = var.z_index_auto(j):var.num_z_planes:var.num_frames;
                    var.stim_onset_corrected = seq(cell2mat(arrayfun(@(x) find(seq-x>0,1),var.stim_onset,'UniformOutput',false)));
                    if var.multi_file == 1
                        frame_list(1,:) = 1:sum(var.frame_per_file);
                    else
                        frame_list(1,:) = 1:var.frame_per_file;
                    end
                    frame_list(2,:) = cell2mat(arrayfun(@(x,y) ones(1,x)*y,var.frame_per_file,1:length(var.frame_per_file),'UniformOutput',false));
                    frame_list(3,:) = cell2mat(arrayfun(@(x,y) 1:x, var.frame_per_file, 1:length(var.frame_per_file), 'UniformOutput', false));
                    var.frame_list_semi_auto = frame_list;
                    example_frame_size = size(imread(var.fileName,j));
                    
                    for trial = 1:length(var.stim_onset_corrected)
                        stim_index = find(seq==var.stim_onset_corrected(trial));
                        pre_stim_index = seq((stim_index-var.pre_stim_frame):stim_index-1);
                        post_stim_index = seq(stim_index:(stim_index+var.post_stim_frame)-1);
                        
                        which_vid = var.frame_list_semi_auto(2,[pre_stim_index post_stim_index]);
                        which_frame = var.frame_list_semi_auto(3,[pre_stim_index post_stim_index]);
                        
                        current_trial = zeros(example_frame_size(1),example_frame_size(2),length(which_vid));
                        cropped_trial = imresize(current_trial(var.ymin:var.ymax,var.xmin:var.xmax,length(which_vid)),1/var.scale_factor);
                        
                        for i = 1:length(which_vid)
                            current_trial(:,:,i) = imread([var.path var.file{which_vid(i)}], which_frame(i));
                            cropped_trial(:,:,i) = imresize(current_trial(var.ymin:var.ymax,var.xmin:var.xmax,i),1/var.scale_factor);
                        end
                        voxel_response(j,trial,:,:) = mean(cropped_trial(:,:,var.pre_stim_frame+1:(var.pre_stim_frame+var.post_stim_frame)),3)...
                            - mean(cropped_trial(:,:,1:var.pre_stim_frame),3);
                        
                        var.all_cropped_trial_auto{j}{trial} = cropped_trial;
                        clear current_trial cropped_trial
                    end
                    var.mean_voxel_response_auto{j} = squeeze(mean(squeeze(voxel_response(j,:,:,:)),1));
                end
                close(f)
                var.max_value = max(cell2mat(cellfun(@(x) max(max(x)), var.mean_voxel_response_auto, 'UniformOutput', false)'));
                var.min_value = min(cell2mat(cellfun(@(x) min(min(x)), var.mean_voxel_response_auto, 'UniformOutput', false)'));
            else
                var.mean_voxel_response_auto = var.mean_voxel_response_semi_auto(var.z_index_auto);
                var.max_value = max(cell2mat(cellfun(@(x) max(max(x)), var.mean_voxel_response_auto, 'UniformOutput', false)'));
                var.min_value = min(cell2mat(cellfun(@(x) min(min(x)), var.mean_voxel_response_auto, 'UniformOutput', false)'));
                var.all_cropped_trial_auto = var.all_cropped_trial_semi_auto(var.z_index_auto);
            end
            
            % Find Best Points
            imagesc(app.UIAxes8,var.mean_voxel_response_auto{var.z_index_auto==var.zvalue},[var.min_value, var.max_value]);
            app.ZPlaneSlider_3.MajorTicks = var.min_z_plane:1:var.max_z_plane;
            app.ZPlaneSlider_3.Limits = [var.min_z_plane var.max_z_plane];
            var.z_index_auto = var.min_z_plane:var.max_z_plane;
            for z = 1:length(var.z_index_auto)
                var.best_list{z} = [];
                current_image = var.mean_voxel_response_auto{z};
                for roi = 1:var.ROI_num
                    var.c = z;
                    [ ~, ix ] = max(current_image(:));
                    [y_coord, x_coord] = ind2sub(size(current_image), ix );
                    var.best_list{z}(roi,:) = [x_coord; y_coord];
                    current_image(y_coord,x_coord)=-1;
                    current_image(y_coord+1,x_coord+1)=-1;
                    current_image(y_coord+1,x_coord)=-1;
                    current_image(y_coord,x_coord+1)=-1;
                    if x_coord == 1
                        if y_coord ~= 1
                            current_image(y_coord-1,x_coord)=-1;
                            current_image(y_coord-1,x_coord+1)=-1;
                        end
                    else
                        if y_coord == 1
                            % if only y are at min edge
                            current_image(y_coord,x_coord-1)=-1;
                            current_image(y_coord+1,x_coord-1)=-1;
                        else
                            % normal
                            current_image(y_coord-1,x_coord-1)=-1;
                            current_image(y_coord-1,x_coord)=-1;
                            current_image(y_coord,x_coord-1)=-1;
                            current_image(y_coord-1,x_coord+1)=-1;
                            current_image(y_coord+1,x_coord-1)=-1;
                        end
                    end
                end
            end
            
            for z = 1:length(var.z_index_auto)
                for roi = 1:size(var.best_list{var.z_index_auto==var.zvalue},1)
                    for trial = 1:length(var.stim_onset_corrected)
                        response_temp = squeeze(var.all_cropped_trial_auto{z}{trial}(var.best_list{z}(roi,2),var.best_list{z}(roi,1),:))';
                        var.best_list_response{z}{roi}{trial} = (response_temp-nanmean(response_temp(1:var.pre_stim_frame)))/nanmean(response_temp(1:var.pre_stim_frame));
                        clear response_temp
                    end
                end
            end
            
        end

        % Value changed function: ZPlaneSlider_3
        function ZPlaneSlider_3ValueChanged(app, event)
            global var
            var.zvalue = round(app.ZPlaneSlider_3.Value);
            var.zvalue_auto = find(var.z_index_auto==var.zvalue);
            if var.zvalue ~= 0
                hold(app.UIAxes8, 'off')
                imagesc(app.UIAxes8,var.mean_voxel_response_auto{var.z_index_auto==var.zvalue},[var.min_value, var.max_value]);
                hold(app.UIAxes8, 'on')
                for roi = 1:var.ROI_num
                    plot(app.UIAxes8,var.best_list{var.zvalue_auto}(roi,1),var.best_list{var.zvalue_auto}(roi,2),'ro')
                end
                app.planeDisplay_3.Value = var.zvalue;
                app.UIAxes8.YLim = [1 size(var.mean_voxel_response_auto{var.zvalue_auto},1)];
                app.UIAxes8.XLim = [1 size(var.mean_voxel_response_auto{var.zvalue_auto},2)];
            end
            
            hold(app.UIAxes9, 'off')
            var.current_roi = 1;
            for trial = 1:length(var.stim_onset_corrected)
                plot(app.UIAxes9,-var.pre_stim_frame:var.post_stim_frame-1,var.best_list_response{var.z_index_auto==var.zvalue}{var.current_roi}{trial})
                hold(app.UIAxes9, 'on')
                plot(app.UIAxes9,[0 0],ylim,'k')
            end
            plot(app.UIAxes8,var.best_list{var.zvalue_auto}(var.current_roi,1),var.best_list{var.zvalue_auto}(var.current_roi,2),'go')
            close all
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global var
            if var.current_roi == var.ROI_num
                var.current_roi = 0;
            end
            var.current_roi = var.current_roi + 1;
            hold(app.UIAxes9, 'off')
            for trial = 1:length(var.stim_onset_corrected)
                plot(app.UIAxes9,-var.pre_stim_frame+1:var.post_stim_frame,var.best_list_response{var.z_index_auto==var.zvalue}{var.current_roi}{trial})
                hold(app.UIAxes9, 'on')
                plot(app.UIAxes9,[0 0],ylim,'k')
            end
            for roi = 1:var.ROI_num
                plot(app.UIAxes8,var.best_list{var.zvalue_auto}(roi,1),var.best_list{var.zvalue_auto}(roi,2),'ro')
            end
            plot(app.UIAxes8,var.best_list{var.zvalue_auto}(var.current_roi,1),var.best_list{var.zvalue_auto}(var.current_roi,2),'go')
            close all
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            global var
            if var.current_roi == 1
                var.current_roi = var.ROI_num+1;
            end
            var.current_roi = var.current_roi - 1;
            hold(app.UIAxes9, 'off')
            for trial = 1:length(var.stim_onset_corrected)
                plot(app.UIAxes9,-var.pre_stim_frame+1:var.post_stim_frame,var.best_list_response{var.z_index_auto==var.zvalue}{var.current_roi}{trial})
                hold(app.UIAxes9, 'on')
                plot(app.UIAxes9,[0 0],ylim,'k')
            end
            for roi = 1:var.ROI_num
                plot(app.UIAxes8,var.best_list{var.zvalue_auto}(roi,1),var.best_list{var.zvalue_auto}(roi,2),'ro')
            end
            plot(app.UIAxes8,var.best_list{var.zvalue_auto}(var.current_roi,1),var.best_list{var.zvalue_auto}(var.current_roi,2),'go')
            close all
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global var
            clear new_coord_points_3
            var.count_auto = var.count_auto + 1;
            current_x = var.best_list{var.zvalue_auto}(var.current_roi,1);
            current_y = var.best_list{var.zvalue_auto}(var.current_roi,2);
            
            new_coord_points_3 = [var.count_auto ...
                current_x*(var.scale_factor)+var.xmin ...
                current_y*(var.scale_factor)+var.ymin ...
                var.zvalue ...
                var.mean_voxel_response_auto{var.z_index_auto==var.zvalue}(current_y,current_x) ...
                {false}];
            var.coord_points = [var.coord_points; new_coord_points_3];
            app.coordinateList_3.Data = array2table(var.coord_points);
        end

        % Button pushed function: ExportButton_3
        function ExportButton_3Pushed(app, event)
            coord_array_3 = table2array(app.coordinateList_3.Data);
            coord_array_valid_temp = reshape([coord_array_3{:}],size(coord_array_3,1),[]);
            auto_coordinates = coord_array_valid_temp(coord_array_valid_temp(:,6)==1,2:4);
            
            [filename, pathname] = uiputfile('*.mat','Save Coordinate Variables As');
            newfilename = fullfile(pathname, filename);
            save(newfilename, 'auto_coordinates');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ResponseButton = uibutton(app.ManualTab, 'push');
            app.ResponseButton.ButtonPushedFcn = createCallbackFcn(app, @ResponseButtonPushed, true);
            app.ResponseButton.Tooltip = {'Compute the response of each pixel to the stimulus. Response window is specified by config file.'};
            app.ResponseButton.Text = 'Response';
            app.planeDisplay = uieditfield(app.ManualTab, 'numeric');
            app.planeDisplay.ValueChangedFcn = createCallbackFcn(app, @planeDisplayValueChanged, true);
            app.SelectButton = uibutton(app.ManualTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Tooltip = {'Select area of interest. First point selects the bottom left coordinates, second point selects the top right coordinates.'};
            app.SelectButton.Text = 'Select';
            app.ZPlaneSlider = uislider(app.ManualTab);
            app.ZPlaneSlider.Limits = [0 50];
            app.ZPlaneSlider.ValueChangedFcn = createCallbackFcn(app, @ZPlaneSliderValueChanged, true);
            app.ZPlaneSlider.MinorTicks = [];
            app.ZPlaneSliderLabel = uilabel(app.ManualTab);
            app.ZPlaneSliderLabel.Text = 'Z Plane';
            app.ResolutionFactorKnob = uiknob(app.ManualTab, 'discrete');
            app.ResolutionFactorKnob.Items = {'Off', '1', '2', '4', '6', '8', '10'};
            app.ResolutionFactorKnob.ItemsData = {'0', '1', '2', '4', '6', '8', '10'};
            app.ResolutionFactorKnob.ValueChangedFcn = createCallbackFcn(app, @ResolutionFactorKnobValueChanged, true);
            app.ResolutionFactorKnob.Tooltip = {'Change resolution reduction factor for speed'};
            app.ResolutionFactorKnob.Value = '0';
            app.ResolutionFactorKnobLabel = uilabel(app.ManualTab);
            app.ResolutionFactorKnobLabel.Text = 'Resolution Factor';
            app.XEditFieldLabel = uilabel(app.ManualTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ManualTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.ManualTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ManualTab, 'numeric');
            app.LoadTiffButton = uibutton(app.ManualTab, 'push');
            app.LoadTiffButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTiffButtonPushed, true);
            app.LoadTiffButton.Tooltip = {'Import raw tiff from SPIM. You can upload mulitple consecutive tiffs. '};
            app.LoadTiffButton.Text = 'Load Tiff';
            app.LoadStimButton = uibutton(app.ManualTab, 'push');
            app.LoadStimButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimButtonPushed, true);
            app.LoadStimButton.Tooltip = {'Load stimulus file. File must contain variable "stim_time". Stimulus time must be based on raw frame time (not segmented frame time)'};
            app.LoadStimButton.Text = 'Load Stim';
            app.SavePositionButton = uibutton(app.ManualTab, 'push');
            app.SavePositionButton.ButtonPushedFcn = createCallbackFcn(app, @SavePositionButtonPushed, true);
            app.SavePositionButton.Tooltip = {'Save the position for export'};
            app.SavePositionButton.Text = 'Save Position';
            app.ExportButton = uibutton(app.ManualTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {'Export ''Keep'' coordinates as mat file'};
            app.ExportButton.Text = 'Export';
            app.AverageButton = uibutton(app.ManualTab, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Tooltip = {'Averages the current z-plane by n number of frames as set by config file'};
            app.AverageButton.Text = 'Average';
            app.coordinateList = uitable(app.ManualTab);
            app.coordinateList.ColumnName = {'Cell'; 'X'; 'Y'; 'Z'; 'Response'; 'Keep'};
            app.coordinateList.RowName = {};
            app.coordinateList.ColumnEditable = [false false false false false true];
            app.PlotPostionButton = uibutton(app.ManualTab, 'push');
            app.PlotPostionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPostionButtonPushed, true);
            app.PlotPostionButton.Tooltip = {'Plot selected position on main window and zoomed window'};
            app.PlotPostionButton.Text = 'Plot Postion';
            app.SelectCellButton = uibutton(app.ManualTab, 'push');
            app.SelectCellButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCellButtonPushed, true);
            app.SelectCellButton.Tooltip = {'Select ROI for futher insepction'};
            app.SelectCellButton.Text = 'Select Cell';
            app.ManualButton = uibutton(app.ManualTab, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Tooltip = {'Manual selection of ROI'};
            app.ManualButton.Text = 'Manual';
            app.LoadConfigButton = uibutton(app.ManualTab, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.UIAxes = uiaxes(app.ManualTab);
            title(app.UIAxes, 'Main Window')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ManualTab);
            title(app.UIAxes2, 'Zoom')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ManualTab);
            title(app.UIAxes3, 'Resolution')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ManualTab);
            title(app.UIAxes4, 'Response')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.UIAxes5 = uiaxes(app.ManualTab);
            title(app.UIAxes5, 'Response Trace')
            xlabel(app.UIAxes5, 'Time (frame)')
            ylabel(app.UIAxes5, 'DF/F')
            app.SemiautoTab = uitab(app.TabGroup);
            app.SemiautoTab.Title = 'Semi-auto';
            app.RunScanButton = uibutton(app.SemiautoTab, 'push');
            app.RunScanButton.ButtonPushedFcn = createCallbackFcn(app, @RunScanButtonPushed, true);
            app.RunScanButton.Text = 'Run Scan';
            app.SelectCellButton_2 = uibutton(app.SemiautoTab, 'push');
            app.SelectCellButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectCellButton_2Pushed, true);
            app.SelectCellButton_2.Text = 'Select Cell';
            app.ZPlaneSlider_2Label = uilabel(app.SemiautoTab);
            app.ZPlaneSlider_2Label.Text = 'Z-Plane';
            app.ZPlaneSlider_2 = uislider(app.SemiautoTab);
            app.ZPlaneSlider_2.Limits = [0 50];
            app.ZPlaneSlider_2.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.ZPlaneSlider_2.ValueChangedFcn = createCallbackFcn(app, @ZPlaneSlider_2ValueChanged, true);
            app.ZPlaneSlider_2.MinorTicks = [];
            app.planeDisplay_2 = uieditfield(app.SemiautoTab, 'numeric');
            app.planeDisplay_2.ValueChangedFcn = createCallbackFcn(app, @planeDisplay_2ValueChanged, true);
            app.coordinateList_2 = uitable(app.SemiautoTab);
            app.coordinateList_2.ColumnName = {'Cell'; 'Z'; 'X'; 'Y'; 'Response'; 'Keep'};
            app.coordinateList_2.RowName = {};
            app.coordinateList_2.ColumnSortable = [true true true true true true];
            app.coordinateList_2.ColumnEditable = [false false false false false true];
            app.SavePositionButton_2 = uibutton(app.SemiautoTab, 'push');
            app.SavePositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavePositionButton_2Pushed, true);
            app.SavePositionButton_2.Text = 'Save Position';
            app.ExportButton_2 = uibutton(app.SemiautoTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.UIAxes6 = uiaxes(app.SemiautoTab);
            title(app.UIAxes6, 'Response Window')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes7 = uiaxes(app.SemiautoTab);
            title(app.UIAxes7, 'Response Trace')
            xlabel(app.UIAxes7, 'Time (frames)')
            ylabel(app.UIAxes7, 'Delta F/F')
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.ofPointsEditFieldLabel = uilabel(app.AutoTab);
            app.ofPointsEditFieldLabel.Text = '# of Points';
            app.ofPointsEditField = uieditfield(app.AutoTab, 'numeric');
            app.ofPointsEditField.ValueChangedFcn = createCallbackFcn(app, @ofPointsEditFieldValueChanged, true);
            app.ZMaxEditFieldLabel = uilabel(app.AutoTab);
            app.ZMaxEditFieldLabel.Text = 'Z-Max';
            app.ZMaxEditField = uieditfield(app.AutoTab, 'numeric');
            app.ZMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZMaxEditFieldValueChanged, true);
            app.ZMinEditFieldLabel = uilabel(app.AutoTab);
            app.ZMinEditFieldLabel.Text = 'Z-Min';
            app.ZMinEditField = uieditfield(app.AutoTab, 'numeric');
            app.ZMinEditField.ValueChangedFcn = createCallbackFcn(app, @ZMinEditFieldValueChanged, true);
            app.ExportButton_3 = uibutton(app.AutoTab, 'push');
            app.ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_3Pushed, true);
            app.ExportButton_3.Text = 'Export';
            app.RunScanButton_2 = uibutton(app.AutoTab, 'push');
            app.RunScanButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunScanButton_2Pushed, true);
            app.RunScanButton_2.Text = 'Run Scan';
            app.PreviousButton = uibutton(app.AutoTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.AutoTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SaveButton = uibutton(app.AutoTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.coordinateList_3 = uitable(app.AutoTab);
            app.coordinateList_3.ColumnName = {'Cell'; 'X'; 'Y'; 'Z'; 'Resp'; 'Keep'};
            app.coordinateList_3.RowName = {};
            app.coordinateList_3.ColumnSortable = [true true true true true true];
            app.coordinateList_3.ColumnEditable = [false false false false false true];
            app.ZPlaneSlider_3Label = uilabel(app.AutoTab);
            app.ZPlaneSlider_3Label.Text = {'Z-Plane'; ''};
            app.ZPlaneSlider_3 = uislider(app.AutoTab);
            app.ZPlaneSlider_3.Limits = [0 50];
            app.ZPlaneSlider_3.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.ZPlaneSlider_3.ValueChangedFcn = createCallbackFcn(app, @ZPlaneSlider_3ValueChanged, true);
            app.ZPlaneSlider_3.MinorTicks = [];
            app.planeDisplay_3 = uieditfield(app.AutoTab, 'numeric');
            app.UIAxes9 = uiaxes(app.AutoTab);
            title(app.UIAxes9, 'Response Trace')
            xlabel(app.UIAxes9, 'Time (frames)')
            ylabel(app.UIAxes9, 'Delta F/F')
            app.UIAxes8 = uiaxes(app.AutoTab);
            title(app.UIAxes8, 'Response Window')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = response_finder_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end